classdef SLS_Oberflaechenrauheit_final_App_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProgrammMenu                matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        OpenInputFolderMenu         matlab.ui.container.Menu
        OpenOutputFolderMenu        matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        PreferencesMenu             matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        FastModeTab                 matlab.ui.container.Tab
        LoadImageButton             matlab.ui.control.Button
        PrepareButton               matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        CalculateButton             matlab.ui.control.Button
        icon_settings1              matlab.ui.control.Image
        icon_folder1                matlab.ui.control.Image
        icon_trash1                 matlab.ui.control.Image
        icon_calc1                  matlab.ui.control.Image
        icon_settings2              matlab.ui.control.Image
        icon_settings3              matlab.ui.control.Image
        icon_help                   matlab.ui.control.Image
        ScaleLabel                  matlab.ui.control.Label
        LButton_2                   matlab.ui.control.StateButton
        RButton                     matlab.ui.control.StateButton
        LengthScaleEditField        matlab.ui.control.NumericEditField
        DropDown                    matlab.ui.control.DropDown
        ExportButton                matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        ProgrammodeDropDownLabel    matlab.ui.control.Label
        ProgrammodeDropDown         matlab.ui.control.DropDown
        SingleModeTab               matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        MaterialIslandsButton       matlab.ui.control.Button
        UndercutsButton             matlab.ui.control.Button
        PoresButton                 matlab.ui.control.Button
        RoughnessButton             matlab.ui.control.Button
        UndercutsbehindMaterialIslandsButton  matlab.ui.control.Button
        StartmanuallyLabel          matlab.ui.control.Label
        SettingsTab                 matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        ErosionTab                  matlab.ui.container.Tab
        SizeSpinnerLabel            matlab.ui.control.Label
        SizeSpinner                 matlab.ui.control.Spinner
        TypDropDownLabel            matlab.ui.control.Label
        TypDropDown                 matlab.ui.control.DropDown
        ImageTab                    matlab.ui.container.Tab
        ImageTypeDropDownLabel      matlab.ui.control.Label
        ImageTypeDropDown           matlab.ui.control.DropDown
        PlotDropDownLabel           matlab.ui.control.Label
        PlotDropDown                matlab.ui.control.DropDown
        ThresholdpixelSpinnerLabel  matlab.ui.control.Label
        ThresholdpixelSpinner       matlab.ui.control.Spinner
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        ParametersTab               matlab.ui.container.Tab
        TabGroup3                   matlab.ui.container.TabGroup
        MatIslandsTab               matlab.ui.container.Tab
        UndercutsTab                matlab.ui.container.Tab
        PoresTab                    matlab.ui.container.Tab
        RoughnessTab                matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditFieldLabel  matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditField  matlab.ui.control.NumericEditField
        SpacebehindMatIslandsTab    matlab.ui.container.Tab
        PathsTab                    matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        ExportpathEditFieldLabel    matlab.ui.control.Label
        ExportpathEditField         matlab.ui.control.EditField
        BrowseButton_3              matlab.ui.control.Button
        ImportpathEditFieldLabel    matlab.ui.control.Label
        ImportpathEditField         matlab.ui.control.EditField
        ApplyButton                 matlab.ui.control.Button
        ResultsTab                  matlab.ui.container.Tab
        TabGroup3_2                 matlab.ui.container.TabGroup
        MatIslandsTab_2             matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        UndercutsTab_2              matlab.ui.container.Tab
        UIAxes3_2                   matlab.ui.control.UIAxes
        PoresTab_2                  matlab.ui.container.Tab
        UIAxes3_3                   matlab.ui.control.UIAxes
        RoughnessTab_2              matlab.ui.container.Tab
        UIAxes3_4                   matlab.ui.control.UIAxes
        SpacebehindMatIslandsTab_2  matlab.ui.container.Tab
        UIAxes3_5                   matlab.ui.control.UIAxes
        HeaderPhoto                 matlab.ui.control.Image
        FooterPhoto                 matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% set global variables
        %% related to image
        BildPfad = 0;
        BildName = 0;
        BildNamePlusExtention = 0;
        imgBW = 0;
        imgBWnoScale = 0;
        imgEroded = 0;
        imgErod = 0; % ausgerichtetes Erodiertes Bild
        imgOriginal = 0;
        imgOrig = 0; % ausgerichtetes Originalbild
        overallResults = [];
        %         overallResults.Kennwert1 = [];
        %         overallResults.Kennwert2 = [];
        %         overallResults.Kennwert3 = [];
        %         overallResults.Kennwert4 = [];
        %         overallResults.Kennwert5 = [];
        doPlot = 0;
        captionFontSize = 14;
        
        %% surface
        realeKonturX = 0;
        realeKonturY = 0;
        aufKontur = 0;
        FlaecheHinterschneidung = 0;
        Pz_Wert = 0;
        %% scale
        MassstabLinksPosX = 0;
        MassstabLinksPosY = 0;
        MassstabRechtsPosX = 0;
        MassstabRechtsPosY = 0;
        MassstabWert = 1;
        MassstabEinheit = 'px';
        %%
        hfig = 0;
        scaleResolution = 0;
        ExcelKennwerte = 0;
        ExcelDaten = 0;
        AnzahlErzeugteDateien = 1;
        ErzeugteDateien = string(zeros(100,2));
        %% Colors
        gruen=[0.78 0.88 0.58];
        hellgruen=[0.73	0.88	0.48];
        dunkelrot=[0.64	0.08	0.18];
        hellrot=[0.93	0.41	0.50];
        grau=[0.50	0.50	0.50];
        schwarz=[0	0	0];
        rosa=[1.00	0.80	0.80];
        hintergrundwert;
        materialwert;
        schwellwert;
        imageWidth;
        imageHeight;
        erosionType;
        erosionValue;
        
        currentFolder;
        guiImagesPath;
        exportPath;
        functionPath;
        functionPath2017;
        imagePath;
        workingDirectory;
        importDirectory; 
        savingDirectory;
        state;
        initialState;
        ImagesData;
    end
    
    methods (Access = private)
        
        function results = ButtonDown(app)
            get(app.hfig)
        end
    end
    
    methods (Access = public)


        function runAllQualityParametersWithGivenParameters(app)
            
            tic
            CalcQualityParam1(app);
            toc
            
            tic
            CalcQualityParam2(app);
            toc
            
            tic
            CalcQualityParam3(app);
            toc
            
            tic
            CalcQualityParam4(app);
            toc
            
            tic
            CalcQualityParam5(app);
            toc
            
            guidata(app.UIFigure, app);
            set(app.CalculateButton, 'BackgroundColor', 'green');
            
            if app.doPlot
                figure
                subplot(2,3,1)
                imshow(app.overallResults.Kennwert1.coloredLabels)
                title('1. Material Islands')
                subplot(2,3,2)
                imshow(app.overallResults.Kennwert2.coloredLabels)
                title('2. Undercuts')
                subplot(2,3,3)
                imshow(app.overallResults.Kennwert3.coloredLabels)
                title('3. Pores')
                subplot(2,3,4)
                imshow(app.overallResults.Kennwert4.coloredLabels)
                title('4. Roughness')
                subplot(2,3,5)
                imshow(app.overallResults.Kennwert5.coloredLabels)
                title('5. Undercuts behind Material Islands')
                imshow(app.overallResults.Kennwert3.coloredLabels, 'Parent', app.UIAxes3_3);
                imshow(app.overallResults.Kennwert1.coloredLabels, 'Parent', app.UIAxes3);
                imshow(app.overallResults.Kennwert2.coloredLabels, 'Parent', app.UIAxes3_2);
                imshow(app.overallResults.Kennwert4.coloredLabels, 'Parent', app.UIAxes3_4);
                imshow(app.overallResults.Kennwert5.coloredLabels, 'Parent', app.UIAxes3_5);
            end
        end
        
        function CalcQualityParam1(app)
            parameterType = 'Material Islands'; 
            [results] = calculateSurfaceParameters(app, app.imgEroded, 'matIslands', parameterType);
            % visualUpdateOfGUIAfterFirstParameter(handles)
            % defineKennwertAsCompleted(handles, results, '1')
            %% save results
            [app.overallResults.Kennwert1] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam2(app)
            %% find material islands and eliminate
            [originalImageNoMatIslands] = eliminateBlobsFromImage(app.imgEroded);
            %% find pores and eliminate
            [originalImageBothEliminated] = eliminateBlobsFromImage(~originalImageNoMatIslands);
            %% draw vertical lines around undercuts
            [thirdStepImage] = drawVerticalLinesForUndercuts(~originalImageBothEliminated);
            %% detect undercuts ( = detect pores)
            [results] = calculateSurfaceParameters(app, thirdStepImage, 'pores', 'Undercuts');
            if app.doPlot
                figure
                subplot(2, 3, 1)
                imshow(app.imgEroded)
                title('1: Original SW-Image')
                subplot(2, 3, 2)
                imshow(originalImageNoMatIslands)
                title('2. Elimination of Material Islands')
                subplot(2, 3, 3)
                imshow(~originalImageBothEliminated)
                title('3. Elimination of Pores')
                subplot(2, 3, 4)
                imshow(thirdStepImage)
                title('4. Drawing vertical Lines')
                subplot(2, 3, 5)
                imshow(results.coloredLabels)
                title('5. Detected Undercuts')
            end
            %% save results
            [app.overallResults.Kennwert2] = results;
            guidata(app.UIFigure, app);
        end
        
        function app = CalcQualityParam3(app)
            [results] = calculateSurfaceParameters(app, app.imgEroded, 'pores', 'Pores');
            if app.doPlot
                imshow(results.coloredLabels, 'Parent', app.UIAxes3_3);
            end
            [app.overallResults.Kennwert3] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam4(app)
            %% find material islands and eliminate
            [originalImageNoMatIslands] = eliminateBlobsFromImage(app.imgEroded);
            %% find pores and eliminate
            [originalImageBothEliminated] = eliminateBlobsFromImage(~originalImageNoMatIslands);
            %% draw vertical lines around undercuts
            [vertLinesForUndercuts] = drawVerticalLinesForUndercuts(~originalImageBothEliminated);
            %% fill out undercuts
            [undercutsEliminated] = eliminateBlobsFromImage(~vertLinesForUndercuts);
            %% detect hills and valleys
            [roughnessImage] = calcSurfaceRoughness(app, undercutsEliminated);
            %% find roughness areas
            [results] = calculateSurfaceParameters(app, roughnessImage, 'pores', 'Roughness');
            [app.overallResults.Kennwert4] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam5(app)
            %% find pores and eliminate
            [poresEliminated] = eliminateBlobsFromImage(~app.imgEroded);
            %% store matIslands data
            [resultsMatIslands] = calculateSurfaceParameters(app, ~poresEliminated, 'matIslands', 'Space behind material islands');
            %% detect matIslands data and delete
            [matIslandsEliminated] = eliminateBlobsFromImage(~poresEliminated);
            %% draw vertical lines around undercuts
            [vertLinesForUndercuts] = drawVerticalLinesForUndercuts(matIslandsEliminated);
            %% fill out undercuts
            [undercutsEliminated] = eliminateBlobsFromImage(~vertLinesForUndercuts);
            %% include material islands again
            againWithMatIslands = undercutsEliminated;
            for i = 2 : length(resultsMatIslands.blobMeasurements)
                thisMatIsland = vertcat(resultsMatIslands.blobMeasurements(i).PixelList);
                for j = 1 : size(thisMatIsland, 1)
                    x = thisMatIsland(j, 2);
                    y = thisMatIsland(j, 1);
                    againWithMatIslands(x, y) = 0;
                end
            end
            %% draw vertical lines around matIslands
            [imageUndercutsBehindMatIslands] = drawVerticalLinesForUndercuts(~againWithMatIslands);
            %% detect undercuts ( = detect pores)
            [results] = calculateSurfaceParameters(app, imageUndercutsBehindMatIslands, 'pores', 'Space behind material islands');
            [app.overallResults.Kennwert5] = results;
            guidata(app.UIFigure, app);
        end
        
        function  saveState(app)
            initialState_tmp = [];
            initialState_tmp.LoadImageButton.BackgroundColor = app.LoadImageButton.BackgroundColor;
            initialState_tmp.LButton_2.BackgroundColor = app.LButton_2.BackgroundColor;
            initialState_tmp.RButton.BackgroundColor = app.RButton.BackgroundColor;
            initialState_tmp.LengthScaleEditField.BackgroundColor = app.LengthScaleEditField.BackgroundColor;
            initialState_tmp.DropDown.BackgroundColor = app.DropDown.BackgroundColor;
            initialState_tmp.PrepareButton.BackgroundColor = app.PrepareButton.BackgroundColor;
            initialState_tmp.CalculateButton.BackgroundColor = app.CalculateButton.BackgroundColor;
            initialState_tmp.ExportButton.BackgroundColor = app.ExportButton.BackgroundColor;
%             datafile = fullfile(pwd,'\MyAppDefaultValues.mat');
%             save(datafile,'initialState_tmp');
            app.initialState = initialState_tmp;
        end
        
        function loadState(app)
            %                 load(pwd,'\MyAppDefaultValues.mat');
            app.LoadImageButton.BackgroundColor = app.initialState.LoadImageButton.BackgroundColor;
            app.LButton_2.BackgroundColor = app.initialState.LButton_2.BackgroundColor;
            app.RButton.BackgroundColor = app.initialState.RButton.BackgroundColor;
            app.LengthScaleEditField.BackgroundColor = app.initialState.LengthScaleEditField.BackgroundColor;
            app.DropDown.BackgroundColor = app.initialState.DropDown.BackgroundColor;
            app.PrepareButton.BackgroundColor = app.initialState.PrepareButton.BackgroundColor;
            app.CalculateButton.BackgroundColor = app.initialState.CalculateButton.BackgroundColor;
            app.ExportButton.BackgroundColor =  app.initialState.ExportButton.BackgroundColor;
            cla(app.UIAxes_2,'reset')
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3_5,'reset')
            cla(app.UIAxes3_4,'reset')
            cla(app.UIAxes3_2,'reset')
            cla(app.UIAxes3,'reset')
            cla(app.UIAxes3_3,'reset')
        end
        
        function choice = userChooseScaleRectangle(app)
            imshow(app.imgBW)
            text(200, 1800, ['Please draw a rectangle', newline, 'around the scale bar', newline, 'incl. the scale value'], ...
                "FontSize", 20, "Color", '#b02e4a', "FontWeight","bold")
            rect = getrect;
            rectrd = round(rect);
            imgBw_tmp = app.imgBW;
            xVals = rectrd(1):(rectrd(1) + rectrd(3));
            yVals =  rectrd(2):(rectrd(2) + rectrd(4));
            imgBw_tmp(yVals, xVals) = 1;
            delete(findall(gcf,'type','text'))
            imshow(imgBw_tmp)
            choice = menu('Was the scale removed successfully?', options);
        end
        
        function updatePathUserChoosen(app)
            app.importDirectory = uigetdir(path);
            try
                app.ImportpathEditField.Value = app.importDirectory;
            catch
                disp('path has not been changed.')
            end
        end
        
        function userSelectsImageInExplorer(app)
            [FileName, PathName]  = uigetfile(  ...
                {'*.jpg;*.jpeg;*.png;*.tif;*.tiff;*.psd',...
                'picture Files (*.jpg,*.jpeg,*.png,*.tif,*.tiff,*.psd)';
                '*.jpg;*.jpeg',  'jpg files (*.jpg,*.jpeg)'; ...
                '*.png',  'png files (*.png)'; ...
                '*.*',  'All Files (*.*)'}, 'Bitte wählen Sie ein Mikroskopiebild aus', app.ImportpathEditField.Value);
            filename = [PathName, FileName];
            [pathstr, name, ext] = fileparts(filename);
            if isequal(FileName,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', filename])
            end
            app.BildPfad = [pathstr '\'];
            app.BildName = name;
            app.BildNamePlusExtention = [name, ext];
        end
        
        function [idxOmitted] = throwOutImagesWithDifferenAspectRation(app, HorW, S_tmp)
            disp('the following images have a different aspect ratio and are therefore omitted: ')
            [~,~,ix1] = unique(HorW(:, 1));
            if nnz(ix1 == 1) > 0.6*numel(S_tmp)
                idx1 = ix1 == 1;
                idxOmitted = ~idx1;
                if nnz(idxOmitted) ~= 0
                    disp(num2str(S_tmp(idxOmitted).name))
                end
            else
                %% debug
                keyboard
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             gitPath                 = 'C:\git\LKTSurfaceQualification';
            %             LKTFunctions            = fullfile(gitPath, 'functions');
            %             LKTGUIPics              = fullfile(gitPath, 'gui_pics');
            %             LKTTestImages           = fullfile(gitPath, 'images');
            %             addpath(fullfile(LKTFunctions))
            %             addpath(fullfile(LKTFunctions, 'from2017'))
            %             addpath(fullfile(LKTGUIPics))
            %             addpath(fullfile(LKTTestImages))
            LKTPATH        = 'C:\git\LKTSurfaceQualification';
            try
                cd(LKTPATH)
            catch
                
            end
            app.saveState;
            %% add paths to workspace
            app.currentFolder = pwd;
            app.guiImagesPath = [app.currentFolder, '\gui_pics'];
            app.exportPath = [app.currentFolder, '\export'];
            app.functionPath = [app.currentFolder, '\functions'];
            app.functionPath2017 = [app.currentFolder, '\functions\from2017'];
            app.imagePath = [app.currentFolder, '\images'];
            %% safe paths to gui (.UIFigure.paths)
            addpath(app.exportPath);
            addpath(app.guiImagesPath);
            addpath(app.functionPath);
            addpath(app.functionPath2017);
            addpath(app.imagePath);
            if strcmp(app.PlotDropDown.Value, 'Yes')
                app.doPlot = 1;
            elseif strcmp(app.PlotDropDown.Value, 'No')
                app.doPlot = 0;
            end
            
            app.HeaderPhoto.ImageSource = ([app.guiImagesPath, '\LKT_app_banner2.jpg']);
            app.FooterPhoto.ImageSource = ([app.guiImagesPath, '\LKT_app_banner_down.jpg']);
            app.icon_settings1.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_settings2.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_settings3.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_folder1.ImageSource = ([app.guiImagesPath, '\folder.png']);
            app.icon_trash1.ImageSource = ([app.guiImagesPath, '\trash.png']);
            app.icon_calc1.ImageSource = ([app.guiImagesPath, '\calc.png']);
            app.icon_help.ImageSource = ([app.guiImagesPath, '\help.png']);
            
        end

        % Callback function
        function AdjustParametersButtonPushed(app, event)
            app.SelectedTab = app.SettingsTab;
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.TabGroup2; 
        end

        % Image clicked function: icon_settings1
        function icon_settings1Clicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            %% delete the previously opened image and highlight buttons green
            
            delete(app.hfig)
            set(app.DropDown, 'BackgroundColor', 'green');
            set(app.LengthScaleEditField, 'BackgroundColor', 'green');
            
            programMode = app.ProgrammodeDropDown.Value;
            if strcmp(programMode, 'multiple images')
                
                for i = 1 : numel(app.ImagesData)
                    
                    
                end
                
            end

            
            %% calculate threshold
            [app.imageHeight, app.imageWidth, ~] = size(app.imgOriginal);
            pxW = 5; 
            centeredValues = (round(app.imageWidth/2)-pxW):(round(app.imageWidth/2)+pxW);
            upperFractionOfImage = app.imgOriginal(pxW:1:(pxW+10),centeredValues);
            lowerFractionOfImage = app.imgOriginal((app.imageHeight-(3*pxW)):(app.imageHeight-pxW),centeredValues);
            app.hintergrundwert =  mean2(upperFractionOfImage);
            app.materialwert = mean2(lowerFractionOfImage);
            app.schwellwert = (app.hintergrundwert + app.materialwert)/2;
            
            %% grayscale to binary image 
            img = app.imgOriginal;
            if size(img, 3)==3
                img2 = rgb2gray(img);
            else
                img2 = img;
            end
            app.imgBW = img2 > app.schwellwert;
            if app.doPlot
                app.hfig = imtool(app.imgBW, 'InitialMagnification', app.scaleResolution);
                set(app.hfig, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '2. Schwarz-Weiß-Bild');
            end
            
            %% Massstab berechnen und Maßstab vom Bild eliminieren
            %Auslesen der Einheit des Mauestabes und setzen dieser Einheit fuer die Ausgabe
            massstablaenge = get(app.LengthScaleEditField, 'Value');
            app.MassstabEinheit = get(app.DropDown, 'Value');
            rx = app.MassstabRechtsPosX; 
            lx = app.MassstabLinksPosX; 
            app.MassstabWert = massstablaenge/abs(rx - lx);
            

            %% eliminate scale through user interaction: 
            %% User has to draw a rectangle around the scale 
            figure
            imshow(app.imgBW)
            text(200, 1800, ['Please draw a rectangle', newline, 'around the scale bar', newline, 'incl. the scale value'], ... 
                 "FontSize", 20, "Color", '#b02e4a', "FontWeight","bold")
            rect = getrect;
            rectrd = round(rect);
            imgBw_tmp = app.imgBW;
            xVals = rectrd(1):(rectrd(1) + rectrd(3)); 
            yVals =  rectrd(2):(rectrd(2) + rectrd(4));
            imgBw_tmp(yVals, xVals) = 1;
            delete(findall(gcf,'type','text'))
            imshow(imgBw_tmp)
            
            options = {'Yes','No - Try Again'};
            choice = menu('Was the scale removed successfully?', options);
            
            while choice == 2
                %% try again
                choice = userChooseScaleRectangle();
            end
            
            text(200, 1800, ['Looks good!', newline, 'Thanks :)'], ...
                "FontSize", 20, "Color", '#1ba843', "FontWeight","bold")
            pause(1)
            close all 
            app.imgBWnoScale = imgBw_tmp; 

            %% decide weather it's a normal or panorama image 
            widthHeightRatio =  app.imageWidth / app.imageHeight;
            if widthHeightRatio < 1.5 
                imageType = 'normal';
            else 
                imageType = 'panorama'; 
            end 
%             if strcmp(imageType, 'normal')
%                 rectRatio = 0.2;
%                 safetyFactor = 10;
%             elseif strcmp(imageType, 'panorama')
%                 rectRatio = 0.2;
%                 safetyFactor = 10;
%             end
%             
%             %% span a rectangle around the scale
%             yBotton = mean(ly, ry) + safetyFactor;
%             lxS = lx - safetyFactor;
%             rxS = rx + safetyFactor;
%             rectWidth = abs(lxS - rxS);
%             rectHeight = rectRatio * rectWidth;
%             yTop = yBotton - rectHeight - safetyFactor;
%             imgScaleDeleted = app.imgBW;
%             quantZeros = nnz(~imgScaleDeleted(yTop:yBotton, rx:lx));
%             quantOnes = nnz(imgScaleDeleted(yTop:yBotton, rx:lx));
%             if quantOnes > quantZeros
%                 disp(['scale is printed in black font, on white background, ration here: ', num2str(ceil(quantZeros/quantOnes*100)), '%.'])
%                 imgScaleDeleted(yTop:yBotton, rx:lx) = 1;
%             else
%                 disp(['scale is printed in white font, on black background, ration here: ', num2str(ceil(quantZeros/quantOnes*100)), '%.'])
%                 imgScaleDeleted(yTop:yBotton, rx:lx) = 0;
%             end
%             app.imgBWnoScale = imgScaleDeleted;
            if app.doPlot
                app.hfig = imtool(app.imgBWnoScale, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '3. Black/White image, scale deleted');
            end
            
            %% count the material islands that are in the image right now 
            tic
            initialRegionProps = regionprops(app.imgBWnoScale);
            toc
            quantInit = length(initialRegionProps);
            %% filter all objects that are small than filterTS
            if strcmp(imageType, 'normal')
                filterTS = 3;
                P = 1;
            else
                filterTS = 10;
                P = 10; 
            end


            %% Remove small objects from binary image
            areasInit = sort(vertcat(initialRegionProps(:).Area));
            areaAll = sum(areasInit(1:(quantInit-1)));
            initialAreasSmallerTS = nnz((areasInit) <= filterTS);
            BW_tmp = app.imgBWnoScale;
            initialAreasSmallerTS_tmp = initialAreasSmallerTS;
            while initialAreasSmallerTS_tmp > 1
                BW_tmp = bwareaopen(BW_tmp, P); 
                initialRegionProps_tmp = regionprops(BW_tmp);
                disp(['bwareaopen: Of ', num2str(initialAreasSmallerTS), ' objects (< ', ... 
                    num2str(filterTS), 'px) ', num2str(initialAreasSmallerTS - length(initialRegionProps_tmp)), ...
                    ' have been eliminated (current size: ', num2str(P), ')'])
                initialAreasSmallerTS_tmp = nnz(sort(vertcat(initialRegionProps_tmp(:).Area)) <= filterTS);
                P = P + 1;
            end
            
            app.erosionType = get(app.TypDropDown, 'Value'); 
            app.erosionValue = get(app.SizeSpinner, 'Value');
            se = strel(app.erosionType, app.erosionValue); 
            
            %% Morphologically close image
            BW_tmp = imclose(BW_tmp, se);
            i0 = length(initialRegionProps_tmp);
            i1_tmp = length(regionprops(BW_tmp)); 
            i1 = i0 - i1_tmp; 
            disp(['After imclose: ', num2str(i1_tmp), ' objects remain. (', num2str(i1), ' deleted)'])
            
            %% Morphologically open image
            BW_tmp = imopen(BW_tmp, se);
            i2_tmp = length(regionprops(BW_tmp)); 
            i2 = i1_tmp - i2_tmp; 
            disp(['After imopen: ', num2str(i2_tmp), ' objects remain. (', num2str(i2), ' deleted)'])
            
            %% display results
            i3 = round(((quantInit - i2_tmp) / quantInit) * 100, 1); 
            initialRegionProps = regionprops(BW_tmp);
            quantInit = length(initialRegionProps);
            areasInit = sort(vertcat(initialRegionProps(:).Area)); 
            areaAll_after = sum(areasInit(1:(quantInit-1)));
            i4 = round(((areaAll - areaAll_after) / areaAll) * 100, 1);
            disp(['Overall reduction of objects ', num2str(i3), '%'])
            disp(['Reduction in area ', num2str(i4), '%'])
            if app.doPlot
                figure
                subplot(2,1,1)
                imshow(app.imgBWnoScale)
                subplot(2,1,2)
                imshow(BW_tmp)
            end
            app.imgEroded = BW_tmp;
           
            if app.doPlot
                app.hfig = imtool(app.imgEroded, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '4. Erodiertes SW-Bild');
            end
            
            app.imgErod = app.imgEroded;
            
            %% allign horizontally
            boundary = realeKontur(app.imgEroded);
            width = app.imageWidth;
            height = app.imageHeight;
            Start(1,2) = 1;
            Start(1,1) = polyval(polyfit(boundary(:,2),boundary(:,1),1),Start(1,2));
            Ende(1,2) = width;
            Ende(1,1) = polyval(polyfit(boundary(:,2),boundary(:,1),1),Ende(1,2));
            diffLR = Ende(1,1)-Start(1,1); % Unterschied der y-Komponenten
            winkel = atand(diffLR/(width/2));
            imgBWDreh = imrotate(app.imgEroded,winkel,'nearest','crop');
            imgOrigDreh = imrotate(app.imgOriginal, winkel,'nearest','crop');
            %% cut off black lines at corners. ceil: so don't cut off too less
            linkeGrenze=abs(ceil(height/2 * diffLR/(width/2)));
            if linkeGrenze <= 0
                linkeGrenze = 1;
            end
            rechteGrenze=width-floor(height/2 * diffLR/(width/2));
            if rechteGrenze >= width
                rechteGrenze = width-1;
            end
            untereGrenze = ceil(height * tan(deg2rad(winkel)));
            imgBWSchnitt = imgBWDreh(1:(height - abs(untereGrenze)), linkeGrenze:rechteGrenze);
            imgOrigSchnitt = imgOrigDreh(:,linkeGrenze:rechteGrenze);
            app.imgErod = imgBWSchnitt;
            app.imgOrig = imgOrigSchnitt;
            if app.doPlot
                app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '5. Rotated eroded image');
            end
            if app.doPlot
                app.hfig = imtool(app.imgOrig, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
            end
            
            %% usually there wouldn't be a pore now in the lower left corner.
            [height, ~, ~] = size(app.imgErod);
            for i = height : -1 : 1
                if app.imgErod(i,1) == 0
                    continue
                else
                    lastLine = i + 1;
                    break
                end
            end
            for i = 1 : size(app.imgErod,2)
                if app.imgErod(height,i) == 0
                    continue
                else
                    lastRow = i;
                    break
                end
            end
            app.imgErod(lastLine:height, 1:lastRow) = 1;
            
            %% if the first 40 pixels are all black, then it's surely no pore
            if nnz(app.imgErod(height,1:40)) == 0
                %% crop the image up to 'lastLine'
                imgBWSchnittSecond = imgBWSchnitt(1 : lastLine, :);
                app.imgErod = imgBWSchnittSecond;
                imgOrigSchnittSecond = imgOrigSchnitt(1 : lastLine, :);
                app.imgOrig = imgOrigSchnittSecond;
                if app.doPlot
                    app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                    set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '5. Rotated eroded image');
                     app.hfig = imtool(app.imgOrig, 'InitialMagnification', app.scaleResolution);
                    set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '5. Rotated eroded image');
                end
            end
            
            %% plot the contours
            Massstab = app.MassstabWert;
            img = app.imgErod;
            boundary = realeKontur(img);
            [heightBoundary,~] = size(boundary);
            [height,width,~] = size(img);
            
            %Laenge der realen Kontur ausrechnen
            laengeOberfl=0;
            for i =2:heightBoundary
                laengeOberfl = laengeOberfl + sqrt((boundary(i,1)-boundary(i-1,1))^2 + (boundary(i,2)-boundary(i-1,2))^2);
            end
            app.realeKonturY = boundary(:,1);
            app.realeKonturX = boundary(:,2);
            disp(['real contour length: ', num2str(round(laengeOberfl*Massstab)), ' µm / ', ...
                num2str(laengeOberfl), ' px.']);
            disp(['picture width: ', num2str(round(width*Massstab)), ' µm / ', ...
                num2str(width), ' px.']);
            disp(['ratio: ', num2str(round(laengeOberfl/width, 1))]);
            
            count = 0;
            iZeile = 0;
            %Bestimmung der Oberflaeche durch "Aufsicht"
            zWerte = zeros(width*5,2);
            for i = 1:(width*5)
                iZeile = i + count;
                zWerte(iZeile,2) = i;
                for j= 1:height
                    if img(j,i) == 1
                        zWerte(iZeile,1) = j;
                        % ab dem 2. Pixel vergleichen, ob ein Hinterschnitt
                        % vorliegen kuennte (also die Hoehendifferenz grueueer als 1
                        % ist)
                        if i > 1
                            % Hoehenwert des aktuellen Pixel
                            a = zWerte(iZeile,1);
                            % Hoehenwert des vorherigen Pixel
                            b = zWerte(iZeile-1,1);
                            % wenn die Hoehendifferenz > 1 ist
                            if abs(b - a) > 1
                                %zusuetzlich den vorherigen Pixel schreiben (gleiche
                                % x-Koordinate wie vorheriger Pixel, gleiche
                                % y-Koordinate wie aktueller Pixel
                                % vorheriger Pixel: Hoehe: zWerte(i-1,1), Breite: zWerte(i-1,2)
                                % aktueller Pixel: Hoehe: zWerte(i,1), Breite: zWerte(i,2)
                                % zukuenftiger Pixel: Hoehe: zWerte(i+1,1), Breite: zWerte(i+1,2)
                                
                                % aktueller Pixel wird zu i+1, damit der
                                % zwischengeschobene Pixel i sein kann
                                zWerte(iZeile+1,1) = j;
                                zWerte(iZeile+1,2) = i;
                                zWerte(iZeile,2) = zWerte(iZeile-1,2);
                                zWerte(iZeile,1) = j;
                                
                                
                                % i-Zaehler um 1 erHoehen, da ein zusuetzliches
                                % Pixel in die Liste aufgenommen wurde. Ansonsten
                                % wuerde man beim nuechsten Durchlauf den letzten
                                % Pixel ueberschreiben.
                                count = count + 1;
                            end
                        end
                        break;
                    end
                end
                % Wenn Bildbreite erreicht, For-Schleife beenden
                if iZeile - count >= width - 1
                    break
                end
            end
            
            zWerte = [zWerte((1:iZeile),1),zWerte((1:iZeile),2)];
            app.aufKontur = zWerte;
            Mittel = mean2(app.realeKonturY); % Bild bereits ausgerichtet, deshalb Mittelwertberechnung nun trivial
            
            if app.doPlot
                app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
                hold on; % Ansonsten wuerde das Bild schon fertig pruesentiert werden!
                plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
                plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2);
                plot([1 width],[Mittel Mittel],'b','Linewidth',2);
                hold off; % erst jetzt werden die Linien tatsuechlich ueber das Bild gelegt
                
                app.hfig = imtool(app.imgOrig, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
                hold on; % Ansonsten wuerde das Bild schon fertig pruesentiert werden!
                plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
                plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2);
                plot([1 width],[Mittel Mittel],'b','Linewidth',2);
                hold off; % erst jetzt werden die Linien tatsuechlich ueber das Bild gelegt
            end
            
            imshow(app.imgOrig, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'on')
            plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2, 'Parent', app.UIAxes_2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
            plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2, 'Parent', app.UIAxes_2);
            plot([1 width],[Mittel Mittel],'b','Linewidth', 2, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'off')
            set(app.PrepareButton, 'BackgroundColor', 'green');
            
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %             imshow(app.plots.plot1, 'Parent', app.UIAxes_2);
            %             [FileName, PathName]  = uigetfile( ...
            %                 {[app.workingDirectory, '/*.jpg;*.jpeg;*.png;*.tif;*.tiff;*.psd'],...
            %                 'picture Files (*.jpg,*.jpeg,*.png,*.tif,*.tiff,*.psd)';
            %                 [app.workingDirectory, '/*.jpg;*.jpeg'],  'jpg files (*.jpg,*.jpeg)'; ...
            %                 [app.workingDirectory, '/*.png'],  'png files (*.png)'; ...
            %                 [app.workingDirectory, '/*.*'],  'All Files (*.*)'}, 'Bitte wuehlen Sie ein Mikroskopiebild aus');
            programMode = app.ProgrammodeDropDown.Value;
            if strcmp(programMode, 'multiple images')
                %% first: Check, if all images in the given folder have the same size
                % --> do this, even before the user clicks on load image.
                % Load image afterwards starts automatically - the user
                % only has to "scale" and "rectangle"
                updatePathUserChoosen(app)
                PathName = app.ImportpathEditField.Value;
                S = dir(fullfile(PathName,'*.jpg')); % pattern to match filenames.
                if numel(S) > 1
                    %% everything alright
                    disp([num2str(numel(S)), ' images found.'])
                else
                    disp('no images here')
                    keyboard
                end
                for k = 1:numel(S)
                    info = imfinfo(fullfile(PathName,S(k).name));
                    [ ~ , ImagesData(k).name , ~ ] = fileparts(info.Filename);
                    ImagesData(k).height = info.Height;
                    ImagesData(k).width = info.Width;
                    ImagesData(k).filename = info.Filename;
                end
                
                S_tmp = ImagesData;
                allImagesHeight = vertcat(ImagesData(:).height);
                allImagesWidth = vertcat(ImagesData(:).width);
                allImagesHeight(50, 1) = 5000;
                allImagesWidth(55, 1) = 5000;
                
                if length(unique([allImagesHeight, allImagesWidth])) == 2
                    disp('all images the same')
                else
                    [idxOmitted1] = throwOutImagesWithDifferenAspectRation(app, allImagesWidth, S_tmp);
                    [idxOmitted2] = throwOutImagesWithDifferenAspectRation(app, allImagesHeight, S_tmp);
                    idxOmitted = and(idxOmitted1, idxOmitted2);
                    S_tmp = S_tmp(idxOmitted);
                end
                S = S_tmp;
                disp([num2str(numel(S)), ' images remaining.'])
                %% read first image with scale bar
                message = ('Select your start image. IMPORTANT: The image has to show a scale!');
                msgbox(message, 'Important Notice','warn');
                userSelectsImageInExplorer(app)
                app.ImagesData = ImagesData; 
            else
                userSelectsImageInExplorer(app);
            end
            
            
            %% open new figure with the loaded image
            imgPath = [app.BildPfad app.BildNamePlusExtention]; %Strings werden aneinandergehuengt
            app.imgOriginal = imread(imgPath);
            [height, width,~] = size(app.imgOriginal);
            %% calculate display proportations to make figure best fit
            Pix_SS = get(0, 'screensize');
            ScreenHeight = Pix_SS(1,4);
            ScreenWidth = Pix_SS(1,3);
            FaktorHeight = height / ScreenHeight;
            FaktorWidth = width / ScreenWidth;
            if FaktorHeight > FaktorWidth
                PossibleFigureHeight = ScreenHeight - 100; %ausgemessen - so ist es an meinem PC
                app.scaleResolution = floor(PossibleFigureHeight / height*100) -1;
            else
                app.scaleResolution = (ScreenWidth / width*100)-0.1;
            end
            app.hfig = imtool(app.imgOriginal, 'InitialMagnification', app.scaleResolution);
            set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '1. Originalbild');
            
            %% plot figure into GUI
            imshow(app.imgOriginal, 'Parent', app.UIAxes);
            set(app.LoadImageButton, 'BackgroundColor', 'green');
            guidata(app.LoadImageButton, app);
            
        end

        % Callback function
        function LButtonPushed(app, event)
            
            set(app.LButton, 'BackgroundColor', 'yellow');
            dcm_obj = datacursormode(app.hfig);
            if get(hObject,'Value')==1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
            
                %Position auslesen
                pos = info_struct.Position;
                posX = pos(1,1);
                posY = pos(1,2);
                handles.MassstabLinksPosX = posX;
                handles.MassstabLinksPosY = posY;
            end
            
            set(app.LButton, 'BackgroundColor', 'green');
        end

        % Value changed function: LButton_2
        function LButton_2ValueChanged(app, event)
            value = app.LButton_2.Value;
            set(app.LButton_2, 'BackgroundColor','yellow');
            dcm_obj = datacursormode(app.hfig);
            if value == 1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
                app.MassstabLinksPosX = info_struct.Position(1,1);
                app.MassstabLinksPosY = info_struct.Position(1,2);
                guidata(app.LButton_2, app);
            end
            set(app.LButton_2, 'BackgroundColor', 'green');
        end

        % Value changed function: RButton
        function RButtonValueChanged(app, event)
            value = app.RButton.Value;
            set(app.RButton, 'BackgroundColor','yellow');
            dcm_obj = datacursormode(app.hfig);
            if value == 1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
                app.MassstabRechtsPosX = info_struct.Position(1,1);
                app.MassstabRechtsPosY = info_struct.Position(1,2);
                guidata(app.RButton, app);
            end
            set(app.RButton, 'BackgroundColor', 'green');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            runAllQualityParametersWithGivenParameters(app)
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            if strcmp(value, 'Yes')
                app.doPlot = 1;
            elseif strcmp(value, 'No')
                app.doPlot = 0;
            end
        end

        % Image clicked function: icon_trash1
        function icon_trash1ImageClicked(app, event)
            kill2
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            updatePathUserChoosen(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            updatePathUserChoosen(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FastModeTab;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.loadState;
        end

        % Image clicked function: icon_settings2
        function icon_settings2ImageClicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
            app.TabGroup2.SelectedTab = app.ErosionTab; 
        end

        % Image clicked function: icon_settings3
        function icon_settings3ImageClicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
            app.TabGroup2.SelectedTab = app.ParametersTab;
            app.TabGroup3.SelectedTab = app.RoughnessTab;
        end

        % Size changed function: FastModeTab
        function FastModeTabSizeChanged(app, event)
            position = app.FastModeTab.Position;
            
        end

        % Image clicked function: icon_folder1
        function icon_folder1ImageClicked(app, event)
            eval(['!explorer /e,' cd])
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% export all results to .mat-file
            filename = fullfile(app.ExportpathEditField.Value, [datestr8601, '_overallResults.mat']); 
            overallResults = app.overallResults; 
            save(filename, 'overallResults') 
            disp(['Results saved as "', [datestr8601, '_overallResults.mat'], '" in export folder: "', app.ExportpathEditField.Value, '"'])
            winopen(app.ExportpathEditField.Value);
            
            %% export results to excel / report 
            
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
            app.TabGroup2.SelectedTab = app.ImageTab;             
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            message = sprintf('When the b/w-threshold is calculated, the software automatically grabs grayscale information from the upper and lower part of the image. Choose the size of this pixel area by this parameter.');
            msgbox(message, 'Parameter Explained','help');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            message = sprintf('When the b/w-threshold is calculated, the software automatically grabs grayscale information from the upper and lower part of the image. Choose the size of this pixel area by this parameter.');
msgbox(message, 'Parameter Explained','help');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            message = sprintf('When the b/w-threshold is calculated, the software automatically grabs grayscale information from the upper and lower part of the image. Choose the size of this pixel area by this parameter.');
msgbox(message, 'Parameter Explained','help');
        end

        % Callback function
        function calculateimageseriesCheckBoxValueChanged(app, event)
            programMode = app.calculateimageseriesCheckBox.Value; 
            disp('ProgramMode ', programMode)
        end

        % Value changed function: ProgrammodeDropDown
        function ProgrammodeDropDownValueChanged(app, event)
            value = app.ProgrammodeDropDown.Value;
            disp(['Program mode: ', value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgrammMenu = uimenu(app.UIFigure);
            app.ProgrammMenu.Text = 'Programm';
            app.OpenMenu = uimenu(app.ProgrammMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProgrammMenu);
            app.SaveMenu.Text = 'Save';
            app.ExportMenu = uimenu(app.ProgrammMenu);
            app.ExportMenu.Text = 'Export';
            app.OpenInputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenInputFolderMenu.Text = 'Open Input Folder';
            app.OpenOutputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenOutputFolderMenu.Text = 'Open Output Folder';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FastModeTab = uitab(app.TabGroup);
            app.FastModeTab.SizeChangedFcn = createCallbackFcn(app, @FastModeTabSizeChanged, true);
            app.FastModeTab.Title = 'Fast Mode';
            app.LoadImageButton = uibutton(app.FastModeTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.PrepareButton = uibutton(app.FastModeTab, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.UIAxes_2 = uiaxes(app.FastModeTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.FastModeTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CalculateButton = uibutton(app.FastModeTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.icon_settings1 = uiimage(app.FastModeTab);
            app.icon_settings1.ImageClickedFcn = createCallbackFcn(app, @icon_settings1Clicked, true);
            app.icon_settings1.ImageSource = 'settings.png';
            app.icon_folder1 = uiimage(app.FastModeTab);
            app.icon_folder1.ImageClickedFcn = createCallbackFcn(app, @icon_folder1ImageClicked, true);
            app.icon_folder1.ImageSource = 'folder.png';
            app.icon_trash1 = uiimage(app.FastModeTab);
            app.icon_trash1.ImageClickedFcn = createCallbackFcn(app, @icon_trash1ImageClicked, true);
            app.icon_trash1.ImageSource = 'trash.png';
            app.icon_calc1 = uiimage(app.FastModeTab);
            app.icon_calc1.ImageSource = 'calc.png';
            app.icon_settings2 = uiimage(app.FastModeTab);
            app.icon_settings2.ImageClickedFcn = createCallbackFcn(app, @icon_settings2ImageClicked, true);
            app.icon_settings2.ImageSource = 'settings.png';
            app.icon_settings3 = uiimage(app.FastModeTab);
            app.icon_settings3.ImageClickedFcn = createCallbackFcn(app, @icon_settings3ImageClicked, true);
            app.icon_settings3.ImageSource = 'settings.png';
            app.icon_help = uiimage(app.FastModeTab);
            app.icon_help.ImageSource = 'help.png';
            app.ScaleLabel = uilabel(app.FastModeTab);
            app.ScaleLabel.Text = 'Scale';
            app.LButton_2 = uibutton(app.FastModeTab, 'state');
            app.LButton_2.ValueChangedFcn = createCallbackFcn(app, @LButton_2ValueChanged, true);
            app.LButton_2.Text = 'L';
            app.RButton = uibutton(app.FastModeTab, 'state');
            app.RButton.ValueChangedFcn = createCallbackFcn(app, @RButtonValueChanged, true);
            app.RButton.Text = 'R';
            app.LengthScaleEditField = uieditfield(app.FastModeTab, 'numeric');
            app.LengthScaleEditField.Value = 50;
            app.DropDown = uidropdown(app.FastModeTab);
            app.DropDown.Items = {'mm', 'µm', 'nm'};
            app.DropDown.Value = 'µm';
            app.ExportButton = uibutton(app.FastModeTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Image2 = uiimage(app.FastModeTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'FAVPNG_time-machine-airport-time-capsule-macos-backup-apple_4XGXYXSm.png';
            app.Image3 = uiimage(app.FastModeTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'settings.png';
            app.ProgrammodeDropDownLabel = uilabel(app.FastModeTab);
            app.ProgrammodeDropDownLabel.Text = 'Program mode';
            app.ProgrammodeDropDown = uidropdown(app.FastModeTab);
            app.ProgrammodeDropDown.Items = {'single image ', 'multiple images'};
            app.ProgrammodeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgrammodeDropDownValueChanged, true);
            app.ProgrammodeDropDown.Value = 'single image ';
            app.SingleModeTab = uitab(app.TabGroup);
            app.SingleModeTab.Title = 'Single Mode';
            app.UIAxes2 = uiaxes(app.SingleModeTab);
            title(app.UIAxes2, 'Result Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.MaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.MaterialIslandsButton.Text = '1. Material Islands';
            app.UndercutsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsButton.Text = '2. Undercuts';
            app.PoresButton = uibutton(app.SingleModeTab, 'push');
            app.PoresButton.Text = '3. Pores';
            app.RoughnessButton = uibutton(app.SingleModeTab, 'push');
            app.RoughnessButton.Text = '4. Roughness';
            app.UndercutsbehindMaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsbehindMaterialIslandsButton.Text = '5. Undercuts behind Material Islands';
            app.StartmanuallyLabel = uilabel(app.SingleModeTab);
            app.StartmanuallyLabel.Text = 'Start manually';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.ErosionTab = uitab(app.TabGroup2);
            app.ErosionTab.Title = 'Erosion';
            app.SizeSpinnerLabel = uilabel(app.ErosionTab);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.ErosionTab);
            app.SizeSpinner.Value = 2;
            app.TypDropDownLabel = uilabel(app.ErosionTab);
            app.TypDropDownLabel.Text = 'Typ';
            app.TypDropDown = uidropdown(app.ErosionTab);
            app.TypDropDown.Items = {'diamond', 'disk', 'octagon', 'square'};
            app.TypDropDown.Value = 'diamond';
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.ImageTypeDropDownLabel = uilabel(app.ImageTab);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.ImageTab);
            app.ImageTypeDropDown.Items = {'b/w image', 'Original image'};
            app.ImageTypeDropDown.Value = 'b/w image';
            app.PlotDropDownLabel = uilabel(app.ImageTab);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.ImageTab);
            app.PlotDropDown.Items = {'Yes', 'No'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'No';
            app.ThresholdpixelSpinnerLabel = uilabel(app.ImageTab);
            app.ThresholdpixelSpinnerLabel.Text = 'Threshold [pixel]';
            app.ThresholdpixelSpinner = uispinner(app.ImageTab);
            app.ThresholdpixelSpinner.Value = 5;
            app.Button = uibutton(app.ImageTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.ImageTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.ImageTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.ParametersTab = uitab(app.TabGroup2);
            app.ParametersTab.Title = 'Parameters';
            app.TabGroup3 = uitabgroup(app.ParametersTab);
            app.MatIslandsTab = uitab(app.TabGroup3);
            app.MatIslandsTab.Title = '1. MatIslands';
            app.UndercutsTab = uitab(app.TabGroup3);
            app.UndercutsTab.Title = '2. Undercuts';
            app.PoresTab = uitab(app.TabGroup3);
            app.PoresTab.Title = '3. Pores';
            app.RoughnessTab = uitab(app.TabGroup3);
            app.RoughnessTab.Title = '4. Roughness';
            app.Label = uilabel(app.RoughnessTab);
            app.Label.Text = '';
            app.NumberofsectionsalongpicturewidthEditFieldLabel = uilabel(app.RoughnessTab);
            app.NumberofsectionsalongpicturewidthEditFieldLabel.Text = 'Number of sections along picturewidth: ';
            app.NumberofsectionsalongpicturewidthEditField = uieditfield(app.RoughnessTab, 'numeric');
            app.NumberofsectionsalongpicturewidthEditField.Value = 50;
            app.SpacebehindMatIslandsTab = uitab(app.TabGroup3);
            app.SpacebehindMatIslandsTab.Title = '5. Space behind MatIslands';
            app.PathsTab = uitab(app.TabGroup2);
            app.PathsTab.Title = 'Paths';
            app.BrowseButton = uibutton(app.PathsTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ExportpathEditFieldLabel = uilabel(app.PathsTab);
            app.ExportpathEditFieldLabel.Text = 'Export path';
            app.ExportpathEditField = uieditfield(app.PathsTab, 'text');
            app.ExportpathEditField.Value = 'C:\git\LKTSurfaceQualification\export';
            app.BrowseButton_3 = uibutton(app.PathsTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.ImportpathEditFieldLabel = uilabel(app.PathsTab);
            app.ImportpathEditFieldLabel.Text = 'Import path';
            app.ImportpathEditField = uieditfield(app.PathsTab, 'text');
            app.ImportpathEditField.Value = 'C:\git\LKTSurfaceQualification\images';
            app.ApplyButton = uibutton(app.SettingsTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3_2 = uitabgroup(app.ResultsTab);
            app.MatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.MatIslandsTab_2.Title = '1. MatIslands';
            app.UIAxes3 = uiaxes(app.MatIslandsTab_2);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UndercutsTab_2 = uitab(app.TabGroup3_2);
            app.UndercutsTab_2.Title = '2. Undercuts';
            app.UIAxes3_2 = uiaxes(app.UndercutsTab_2);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.PoresTab_2 = uitab(app.TabGroup3_2);
            app.PoresTab_2.Title = '3. Pores';
            app.UIAxes3_3 = uiaxes(app.PoresTab_2);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, '')
            app.RoughnessTab_2 = uitab(app.TabGroup3_2);
            app.RoughnessTab_2.Title = '4. Roughness';
            app.UIAxes3_4 = uiaxes(app.RoughnessTab_2);
            title(app.UIAxes3_4, '')
            xlabel(app.UIAxes3_4, '')
            ylabel(app.UIAxes3_4, '')
            app.SpacebehindMatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.SpacebehindMatIslandsTab_2.Title = '5. Space behind MatIslands';
            app.UIAxes3_5 = uiaxes(app.SpacebehindMatIslandsTab_2);
            title(app.UIAxes3_5, '')
            xlabel(app.UIAxes3_5, '')
            ylabel(app.UIAxes3_5, '')
            app.HeaderPhoto = uiimage(app.UIFigure);
            app.HeaderPhoto.ImageSource = 'LKT_app_banner2.jpg';
            app.FooterPhoto = uiimage(app.UIFigure);
            app.FooterPhoto.ImageSource = 'LKT_app_banner_down.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLS_Oberflaechenrauheit_final_App_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end