classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProgrammMenu                matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        OpenInputFolderMenu         matlab.ui.container.Menu
        OpenOutputFolderMenu        matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        PreferencesMenu             matlab.ui.container.Menu
        HeaderPhoto                 matlab.ui.control.Image
        FooterPhoto                 matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        CalculateTab                matlab.ui.container.Tab
        LoadImageButton             matlab.ui.control.Button
        CalculateAllButton          matlab.ui.control.Button
        icon_settings1              matlab.ui.control.Image
        icon_folder1                matlab.ui.control.Image
        icon_trash1                 matlab.ui.control.Image
        icon_settings2              matlab.ui.control.Image
        icon_help                   matlab.ui.control.Image
        ScaleLabel                  matlab.ui.control.Label
        LengthScaleEditField        matlab.ui.control.NumericEditField
        DropDown                    matlab.ui.control.DropDown
        Image2                      matlab.ui.control.Image
        AnalyzeResultsButton        matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
        Button                      matlab.ui.control.Button
        ProgrammodeDropDown_2Label  matlab.ui.control.Label
        ProgrammodeDropDown         matlab.ui.control.DropDown
        OberflchengteGaugeLabel     matlab.ui.control.Label
        OberflchengteGauge          matlab.ui.control.Gauge
        FastModeDropdown            matlab.ui.control.DropDown
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes7                     matlab.ui.control.UIAxes
        SingleModeTab               matlab.ui.container.Tab
        MaterialIslandsButton       matlab.ui.control.Button
        UndercutsButton             matlab.ui.control.Button
        PoresButton                 matlab.ui.control.Button
        RoughnessButton             matlab.ui.control.Button
        UndercutsbehindMaterialIslandsButton  matlab.ui.control.Button
        StartmanuallyLabel          matlab.ui.control.Label
        Button_5                    matlab.ui.control.Button
        LoadImageButton_2           matlab.ui.control.Button
        SettingsTab                 matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        ErosionTab                  matlab.ui.container.Tab
        SizeSpinnerLabel            matlab.ui.control.Label
        SizeSpinner                 matlab.ui.control.Spinner
        TypDropDownLabel            matlab.ui.control.Label
        TypDropDown                 matlab.ui.control.DropDown
        ImageTab                    matlab.ui.container.Tab
        ImageTypeDropDownLabel      matlab.ui.control.Label
        ImageTypeDropDown           matlab.ui.control.DropDown
        PlotDropDownLabel           matlab.ui.control.Label
        PlotDropDown                matlab.ui.control.DropDown
        ThresholdpixelSpinnerLabel  matlab.ui.control.Label
        ThresholdpixelSpinner       matlab.ui.control.Spinner
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        ParametersTab               matlab.ui.container.Tab
        TabGroup3                   matlab.ui.container.TabGroup
        MatIslandsTab               matlab.ui.container.Tab
        UndercutsTab                matlab.ui.container.Tab
        PoresTab                    matlab.ui.container.Tab
        RoughnessTab                matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditFieldLabel  matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditField  matlab.ui.control.NumericEditField
        SpacebehindMatIslandsTab    matlab.ui.container.Tab
        PathsTab                    matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        ExportpathEditFieldLabel    matlab.ui.control.Label
        ExportpathEditField         matlab.ui.control.EditField
        BrowseButton_2              matlab.ui.control.Button
        ImportpathEditFieldLabel    matlab.ui.control.Label
        ImportpathEditField         matlab.ui.control.EditField
        BrowseButton_3              matlab.ui.control.Button
        ImportpathEditFieldLabel_2  matlab.ui.control.Label
        SeriesFolderPath            matlab.ui.control.EditField
        ApplyButton                 matlab.ui.control.Button
        ResultsTab                  matlab.ui.container.Tab
        TabGroup3_2                 matlab.ui.container.TabGroup
        MatIslandsTab_2             matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        UndercutsTab_2              matlab.ui.container.Tab
        UIAxes3_2                   matlab.ui.control.UIAxes
        PoresTab_2                  matlab.ui.container.Tab
        UIAxes3_3                   matlab.ui.control.UIAxes
        RoughnessTab_2              matlab.ui.container.Tab
        UIAxes3_4                   matlab.ui.control.UIAxes
        SpacebehindMatIslandsTab_2  matlab.ui.container.Tab
        UIAxes3_5                   matlab.ui.control.UIAxes
        Allrightsreserved20172020LehrstuhlfrKunststofftechnikLabel  matlab.ui.control.Label
        AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButton  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        Panel                       matlab.ui.container.Panel
    end

    
    properties (Access = public)
 %% related to image
        Data; 
        ConfigParams;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LKTPATH        = 'C:\git\LKTSurfaceQualification\functions';
            clc % clear all warnings
            try
                cd(LKTPATH)
            catch
                disp('Pull git repository "https://github.com/michaelfischer1992/LKTSurfaceQualification.git" to C:\git\')
                web('https://github.com/michaelfischer1992/LKTSurfaceQualification')
                error('Get git repository first. Path mentioned previously.')
            end
            app = SLS_Oberflaechenrauheit_final_2020(app); 
            clc % clear all warnings
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app = selectImageAndDoFirstImageAdjustments(app); 
        end

        % Button pushed function: CalculateAllButton
        function CalculateAllButtonPushed(app, event)
            app = prepareImageAndcalculateAllParameters(app); 
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            dname = uigetdir(app.ExportpathEditField.Value);
            app.ExportpathEditField.Value = dname; 
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            dname = uigetdir(app.ImportpathEditField.Value);
            app.ImportpathEditField.Value = dname; 
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            dname = uigetdir(app.SeriesFolderPath.Value);
            app.SeriesFolderPath.Value = dname; 
        end

        % Button pushed function: 
        % AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButton
        function AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButtonPushed(app, event)
            web('https://www.lkt.tf.fau.de/das-team/aktuelle-mitarbeiter/kleffel-tobias/')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgrammMenu = uimenu(app.UIFigure);
            app.ProgrammMenu.Text = 'Programm';
            app.OpenMenu = uimenu(app.ProgrammMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProgrammMenu);
            app.SaveMenu.Text = 'Save';
            app.ExportMenu = uimenu(app.ProgrammMenu);
            app.ExportMenu.Text = 'Export';
            app.OpenInputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenInputFolderMenu.Text = 'Open Input Folder';
            app.OpenOutputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenOutputFolderMenu.Text = 'Open Output Folder';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.Text = 'Preferences';
            app.HeaderPhoto = uiimage(app.UIFigure);
            app.HeaderPhoto.ImageSource = 'LKT_app_banner2.jpg';
            app.FooterPhoto = uiimage(app.UIFigure);
            app.FooterPhoto.ImageSource = 'LKT_app_banner_down.jpg';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.LoadImageButton = uibutton(app.CalculateTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CalculateAllButton = uibutton(app.CalculateTab, 'push');
            app.CalculateAllButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllButtonPushed, true);
            app.CalculateAllButton.Text = 'Calculate All';
            app.icon_settings1 = uiimage(app.CalculateTab);
            app.icon_settings1.ImageSource = 'settings.png';
            app.icon_folder1 = uiimage(app.CalculateTab);
            app.icon_folder1.ImageSource = 'folder.png';
            app.icon_trash1 = uiimage(app.CalculateTab);
            app.icon_trash1.ImageSource = 'trash.png';
            app.icon_settings2 = uiimage(app.CalculateTab);
            app.icon_settings2.ImageSource = 'settings.png';
            app.icon_help = uiimage(app.CalculateTab);
            app.icon_help.ImageSource = 'help.png';
            app.ScaleLabel = uilabel(app.CalculateTab);
            app.ScaleLabel.Text = 'Scale';
            app.LengthScaleEditField = uieditfield(app.CalculateTab, 'numeric');
            app.LengthScaleEditField.Value = 50;
            app.DropDown = uidropdown(app.CalculateTab);
            app.DropDown.Items = {'mm', 'µm', 'nm'};
            app.DropDown.Value = 'µm';
            app.Image2 = uiimage(app.CalculateTab);
            app.Image2.ImageSource = 'FAVPNG_time-machine-airport-time-capsule-macos-backup-apple_4XGXYXSm.png';
            app.AnalyzeResultsButton = uibutton(app.CalculateTab, 'push');
            app.AnalyzeResultsButton.Text = 'Analyze Results';
            app.Image3 = uiimage(app.CalculateTab);
            app.Image3.ImageSource = 'settings.png';
            app.Button = uibutton(app.CalculateTab, 'push');
            app.Button.Text = '?';
            app.ProgrammodeDropDown_2Label = uilabel(app.CalculateTab);
            app.ProgrammodeDropDown_2Label.Text = 'Mode';
            app.ProgrammodeDropDown = uidropdown(app.CalculateTab);
            app.ProgrammodeDropDown.Items = {'single image', 'multiple images'};
            app.ProgrammodeDropDown.Value = 'single image';
            app.OberflchengteGaugeLabel = uilabel(app.CalculateTab);
            app.OberflchengteGaugeLabel.Text = 'Oberflächengüte';
            app.OberflchengteGauge = uigauge(app.CalculateTab, 'circular');
            app.FastModeDropdown = uidropdown(app.CalculateTab);
            app.FastModeDropdown.Items = {'normal', 'fast'};
            app.FastModeDropdown.Value = 'normal';
            app.UIAxes6 = uiaxes(app.CalculateTab);
            title(app.UIAxes6, 'Original Image')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.CalculateTab);
            title(app.UIAxes7, 'Image showing all Parameters')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.SingleModeTab = uitab(app.TabGroup);
            app.SingleModeTab.Title = 'Single Mode';
            app.MaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.MaterialIslandsButton.Text = '1. Material Islands';
            app.UndercutsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsButton.Text = '2. Undercuts';
            app.PoresButton = uibutton(app.SingleModeTab, 'push');
            app.PoresButton.Text = '3. Pores';
            app.RoughnessButton = uibutton(app.SingleModeTab, 'push');
            app.RoughnessButton.Text = '4. Roughness';
            app.UndercutsbehindMaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsbehindMaterialIslandsButton.Text = '5. Undercuts behind Material Islands';
            app.StartmanuallyLabel = uilabel(app.SingleModeTab);
            app.StartmanuallyLabel.Text = 'Start manually';
            app.Button_5 = uibutton(app.SingleModeTab, 'push');
            app.Button_5.Text = '?';
            app.LoadImageButton_2 = uibutton(app.SingleModeTab, 'push');
            app.LoadImageButton_2.Text = 'Load Image';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.ErosionTab = uitab(app.TabGroup2);
            app.ErosionTab.Title = 'Erosion';
            app.SizeSpinnerLabel = uilabel(app.ErosionTab);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.ErosionTab);
            app.SizeSpinner.Value = 2;
            app.TypDropDownLabel = uilabel(app.ErosionTab);
            app.TypDropDownLabel.Text = 'Typ';
            app.TypDropDown = uidropdown(app.ErosionTab);
            app.TypDropDown.Items = {'diamond', 'disk', 'octagon', 'square'};
            app.TypDropDown.Value = 'diamond';
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.ImageTypeDropDownLabel = uilabel(app.ImageTab);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.ImageTab);
            app.ImageTypeDropDown.Items = {'b/w image', 'Original image'};
            app.ImageTypeDropDown.Value = 'b/w image';
            app.PlotDropDownLabel = uilabel(app.ImageTab);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.ImageTab);
            app.PlotDropDown.Items = {'Yes', 'No'};
            app.PlotDropDown.Value = 'No';
            app.ThresholdpixelSpinnerLabel = uilabel(app.ImageTab);
            app.ThresholdpixelSpinnerLabel.Text = 'Threshold [pixel]';
            app.ThresholdpixelSpinner = uispinner(app.ImageTab);
            app.ThresholdpixelSpinner.Value = 5;
            app.Button_2 = uibutton(app.ImageTab, 'push');
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.ImageTab, 'push');
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.ImageTab, 'push');
            app.Button_4.Text = '?';
            app.ParametersTab = uitab(app.TabGroup2);
            app.ParametersTab.Title = 'Parameters';
            app.TabGroup3 = uitabgroup(app.ParametersTab);
            app.MatIslandsTab = uitab(app.TabGroup3);
            app.MatIslandsTab.Title = '1. MatIslands';
            app.UndercutsTab = uitab(app.TabGroup3);
            app.UndercutsTab.Title = '2. Undercuts';
            app.PoresTab = uitab(app.TabGroup3);
            app.PoresTab.Title = '3. Pores';
            app.RoughnessTab = uitab(app.TabGroup3);
            app.RoughnessTab.Title = '4. Roughness';
            app.Label = uilabel(app.RoughnessTab);
            app.Label.Text = '';
            app.NumberofsectionsalongpicturewidthEditFieldLabel = uilabel(app.RoughnessTab);
            app.NumberofsectionsalongpicturewidthEditFieldLabel.Text = 'Number of sections along picturewidth: ';
            app.NumberofsectionsalongpicturewidthEditField = uieditfield(app.RoughnessTab, 'numeric');
            app.NumberofsectionsalongpicturewidthEditField.Value = 50;
            app.SpacebehindMatIslandsTab = uitab(app.TabGroup3);
            app.SpacebehindMatIslandsTab.Title = '5. Space behind MatIslands';
            app.PathsTab = uitab(app.TabGroup2);
            app.PathsTab.Title = 'Paths';
            app.BrowseButton = uibutton(app.PathsTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ExportpathEditFieldLabel = uilabel(app.PathsTab);
            app.ExportpathEditFieldLabel.Text = 'Export path';
            app.ExportpathEditField = uieditfield(app.PathsTab, 'text');
            app.ExportpathEditField.Value = 'C:\git\LKTSurfaceQualification\export';
            app.BrowseButton_2 = uibutton(app.PathsTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ImportpathEditFieldLabel = uilabel(app.PathsTab);
            app.ImportpathEditFieldLabel.Text = 'Import path';
            app.ImportpathEditField = uieditfield(app.PathsTab, 'text');
            app.ImportpathEditField.Value = 'C:\git\LKTSurfaceQualification\images';
            app.BrowseButton_3 = uibutton(app.PathsTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.ImportpathEditFieldLabel_2 = uilabel(app.PathsTab);
            app.ImportpathEditFieldLabel_2.Text = 'Series Folder';
            app.SeriesFolderPath = uieditfield(app.PathsTab, 'text');
            app.SeriesFolderPath.Value = 'C:\git\LKTSurfaceQualification\images\images series (normal)';
            app.ApplyButton = uibutton(app.SettingsTab, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3_2 = uitabgroup(app.ResultsTab);
            app.MatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.MatIslandsTab_2.Title = '1. MatIslands';
            app.UIAxes3 = uiaxes(app.MatIslandsTab_2);
            app.UndercutsTab_2 = uitab(app.TabGroup3_2);
            app.UndercutsTab_2.Title = '2. Undercuts';
            app.UIAxes3_2 = uiaxes(app.UndercutsTab_2);
            app.PoresTab_2 = uitab(app.TabGroup3_2);
            app.PoresTab_2.Title = '3. Pores';
            app.UIAxes3_3 = uiaxes(app.PoresTab_2);
            app.RoughnessTab_2 = uitab(app.TabGroup3_2);
            app.RoughnessTab_2.Title = '4. Roughness';
            app.UIAxes3_4 = uiaxes(app.RoughnessTab_2);
            app.SpacebehindMatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.SpacebehindMatIslandsTab_2.Title = '5. Space behind MatIslands';
            app.UIAxes3_5 = uiaxes(app.SpacebehindMatIslandsTab_2);
            app.Allrightsreserved20172020LehrstuhlfrKunststofftechnikLabel = uilabel(app.UIFigure);
            app.Allrightsreserved20172020LehrstuhlfrKunststofftechnikLabel.Text = 'All rights reserved 2017-2020, Lehrstuhl für Kunststofftechnik';
            app.AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButton = uibutton(app.UIFigure, 'push');
            app.AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButton.ButtonPushedFcn = createCallbackFcn(app, @AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButtonPushed, true);
            app.AnsprechpartnerDiplIngTobiasKleffeltobiaskleffelfaudeButton.Text = 'Ansprechpartner: Dipl.-Ing. Tobias Kleffel (tobias.kleffel@fau.de)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'K1'; 'K2'; 'K3'; 'K4'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end